classdef GUI_GlobalBioIm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GlobalBioImGUIUIFigure       matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Main                         matlab.ui.container.Tab
        GlobalBioImLabel             matlab.ui.control.Label
        SaveButton_3                 matlab.ui.control.Button
        SelectButton                 matlab.ui.control.Button
        SelectapipelineDropDown      matlab.ui.control.DropDown
        PipelineEditFieldLabel       matlab.ui.control.Label
        PipelineEditField            matlab.ui.control.EditField
        DescriptionTextAreaLabel     matlab.ui.control.Label
        DescriptionTextArea          matlab.ui.control.TextArea
        CopyrightC2019TheGlobalBioImTeamLabel  matlab.ui.control.Label
        ForwardModel                 matlab.ui.container.Tab
        TextArea_ForwardModel        matlab.ui.control.TextArea
        LabelTextArea                matlab.ui.control.Label
        Button_AddLinOp              matlab.ui.control.Button
        Button_SeeDocLinOp           matlab.ui.control.Button
        OperatorLabel                matlab.ui.control.Label
        DropDown_SelectLinOp         matlab.ui.control.DropDown
        FMPanel                      matlab.ui.container.Panel
        Button_3                     matlab.ui.control.Button
        Next                         matlab.ui.control.Button
        SaveButton_6                 matlab.ui.control.Button
        CostFunction                 matlab.ui.container.Tab
        TextArea_CostFunction        matlab.ui.control.TextArea
        CostFunctionLabel            matlab.ui.control.Label
        Button_Add_DataFidelity      matlab.ui.control.Button
        Button_SeeDocDataFidelity    matlab.ui.control.Button
        DatafidelityLabel            matlab.ui.control.Label
        DropDown_SelectDataFidelity  matlab.ui.control.DropDown
        FMPanel_CostFunction         matlab.ui.container.Panel
        Button_Add_Regul             matlab.ui.control.Button
        Button_SeeDocRegul           matlab.ui.control.Button
        RegularizerLabel             matlab.ui.control.Label
        DropDown_Select_Regul        matlab.ui.control.DropDown
        AddPositivityConstraintCheckBox  matlab.ui.control.CheckBox
        FMPanel_CostFunction_2       matlab.ui.container.Panel
        Button                       matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        SaveButton_5                 matlab.ui.control.Button
        Optimization                 matlab.ui.container.Tab
        FMPanel_CostFunction_3       matlab.ui.container.Panel
        Button_Add_Regul_2           matlab.ui.control.Button
        Button_SeeDoc_Regul_2        matlab.ui.control.Button
        AlgorithmLabel               matlab.ui.control.Label
        DropDown_Select_Regul_2      matlab.ui.control.DropDown
        Button_4                     matlab.ui.control.Button
        RUNButton                    matlab.ui.control.Button
        GenerateScriptButton         matlab.ui.control.Button
        RecapofAllSelectedParametersButton  matlab.ui.control.Button
        DisplaythecostfunctionevolutionCheckBox  matlab.ui.control.CheckBox
        FMPanel_CostFunction_4       matlab.ui.container.Panel
        SaveButton_7                 matlab.ui.control.Button
    end

    % ======================================
    % Programatically added public Properties
    % ======================================
    properties (Access = public)

        LinOpCell={}    % Cell array containing the LinOps (ForwardModel panel)
                        % Each element of this cell contains a structure with fields
                        % - name     (name of the LinOp)
                        % - label    (Text with LinOp name in the zone where selected LinOps are displayed)
                        % - dlabel   (Text with LinOp notation -- e.g H1 -- in the zone where selected LinOps are displayed)
                        % - butDel   (delete button)
                        % - butUp    (up button)
                        % - butDown  (down button)
                        % - notation (letter used to represent the LinOp)
                        % - call     (command line used to instanciate the LinOp)
                        % - parameters (cell with fields: name/type/val)
                        % - SetPropGUI (contains infos for displaying the GUI Set Parameters window)
        
        DFstruct = struct(); % Structure containing the data-fidelity term (CostFunction panel)
        
        RegulCell={}   % Cell array containing the regularizations (CostFunction panel)
        
        logwd=[]       % Log window
        
        script=struct('header',{{'%----------------------------------------------'}}, ...
            'params',{{'%% Load/Read Parameters'}}, ...
            'linop',{{'%% Instanciate the Forward Model'}}, ...
            'cost',{{'%% Instanciate the Cost function'}}, ...
            'algo',{{'%% Instanciate and Run the Optimization method'}});
        
        pipeline    % structure with fields
                    %    -name  (name of the pipeline)
                    %    -dscpt (description of the pipeline)
    end
    
    % ======================================
    % Programatically added private Properties
    % ======================================
    properties (Access = private)
        
        % Global properties
        doc_weblink = 'biomedical-imaging-group.github.io/GlobalBioIm/'; % Link to online documentation
        
        % Properties for ForwardModel panel
        maxLinOp         = 6;      % maximum number of concatenable linOps
        linOpText        = [];     % info displayed in text area for user
        
        % Properties for CostFunction panel
        maxRegul         = 3;      % maximum number of regularization terms
        costText         = [];     % info displayed in text area for user
        dfListGUI        = {'Quadratic (L2)', 'Kullback-Leibler (KL)'}; % proposed data-fidelity terms (in GUI)
        dfListCode       = {'L2', 'KullLeib'};                          % corresponding data-fidelity terms (in code)
        regListGUI       = {'Tikhonov (Tikh)', 'Total-Variation (TV)', 'Hessian-Schatten (HS)'}; % proposed regularization terms (in GUI)
        regCostListCode  = {'L2', 'MixNorm21', 'MixNormSchatt1'}; % corresponding regularization costs (in code)
        regLinOpListCode = {'Grad', 'Grad', 'Hess'}; % corresponding regularization operators (in code)
        
        % Information for display
        pos_left      = 50;
        pos_bott_init = 325;
        incr_height   = 50;
        def_width     = 30;
        def_height    = 20;
        
    end
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
    % ======================================
    %           COMMON METHODS
    % ======================================
    methods (Access = private)    
        % -- Open Log window
        function OpenLog(app,msg)
            app.logwd.fig = uifigure;
            app.logwd.fig.Position =app.GlobalBioImGUIUIFigure.Position  +  [0 app.GlobalBioImGUIUIFigure.Position(4)/3 0 -app.GlobalBioImGUIUIFigure.Position(4)*2/3];
            app.logwd.fig.Name  = 'Log';
            app.logwd.TextArea = uitextarea(app.logwd.fig);
            app.logwd.TextArea.Value = msg;
            app.logwd.TextArea.FontColor='red';
            app.logwd.TextArea.Position = [10 10 app.logwd.fig.Position(3)-20 app.logwd.fig.Position(4)-20];
            app.logwd.TextArea.Editable = 'off';
        end   
        
    end
    
    % ======================================
    %          MAIN PANEL METHODS
    % ======================================
    methods (Access = private)
        % -- Set list of Pipelines in dropdown menu
        function ListOfPipelines = GetListOfAvailablePipelines(app)
            dirList=struct2cell(dir('pipelines/*mat'));
            dirList=dirList(1,:);
            ListOfPipelines=strrep(dirList,'.mat','');
        end
    end
 
    % ======================================
    %      FORWARD MODEL PANEL METHODS
    % ======================================
    methods (Access = private)
        % -- Set list of linOps in dropdown menu
        function ListOfLinOpsStr = GetListOfAvailableLinOps(app)
            dirList1 = struct2cell(dir('../LinOp/LinOp*.m'));
            dirList2 = struct2cell(dir('../LinOp/SelectorLinOps/LinOp*.m'));
            dirList = [dirList1 dirList2];
            idx=[];
            for ii=1:length(dirList)
                if ~isempty(regexp(fileread(dirList{1,ii}),'GUI-Header','once'))
                    idx=[idx,ii];
                end
            end
            dirList=dirList(1,idx);
            ListOfLinOpsStr = strrep(strrep(dirList,'.m',''),'LinOp','');
        end
        
        % -- Delete hierarchy of linOps
        function deleteHierarchyLinOps(app)
            % Number of LinOps
            nLinOps = length(app.LinOpCell);
            
            % Loop over all the selected LinOps
            for idx=1:nLinOps
                delete(app.LinOpCell{idx}.label);
                delete(app.LinOpCell{idx}.butProp);
                delete(app.LinOpCell{idx}.butDel);
                delete(app.LinOpCell{idx}.ddlabel);
                delete(app.LinOpCell{idx}.butUp);
                delete(app.LinOpCell{idx}.butDown);
            end
        end
        
        % -- Create hierarchy of linOps
        function createHierarchyLinOps(app)
            
            % Number of LinOps
            nLinOps = length(app.LinOpCell);
            
            % Loop over all the selected LinOps
            app.linOpText = 'H = ';
            for idx=1:nLinOps
                % Position info
                pos_bott      = app.pos_bott_init-(idx-1)*app.incr_height;
                
                % Add linOp name info
                app.LinOpCell{idx}.label = uilabel(app.ForwardModel);
                app.LinOpCell{idx}.label.Text = ['LinOp' ,app.LinOpCell{idx}.name];
                app.LinOpCell{idx}.label.Position = [app.pos_left+40 pos_bott app.def_width*4 app.def_height];
                
                % Update text for concatenation of linOps
                app.linOpText = [app.linOpText app.LinOpCell{idx}.notation];
                
                % Create label of drop-down component
                app.LinOpCell{idx}.ddlabel = uilabel(app.ForwardModel);
                app.LinOpCell{idx}.ddlabel.Position = [app.pos_left pos_bott app.def_width*1.5 app.def_height];
                app.LinOpCell{idx}.ddlabel.FontWeight = 'bold';
                app.LinOpCell{idx}.ddlabel.FontSize = 16;
                app.LinOpCell{idx}.ddlabel.Text = [app.LinOpCell{idx}.notation, ' : '];
                
                % Create button for setting properties
                app.LinOpCell{idx}.butProp = uibutton(app.ForwardModel, 'push');
                app.LinOpCell{idx}.butProp.Text = 'Set Parameters';
                app.LinOpCell{idx}.butProp.FontWeight = 'bold';
                app.LinOpCell{idx}.butProp.Position = [app.pos_left+180 pos_bott app.def_width*3.2 app.def_height];
                app.LinOpCell{idx}.butProp.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_PropButtonPushed(app,idx), true);
                
                % Create delete button
                app.LinOpCell{idx}.butDel = uibutton(app.ForwardModel, 'push');
                app.LinOpCell{idx}.butDel.Text = '';
                app.LinOpCell{idx}.butDel.Icon = 'trashIcon.png';
                app.LinOpCell{idx}.butDel.Position = [app.pos_left+340 pos_bott app.def_width*0.8 app.def_height];
                app.LinOpCell{idx}.butDel.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_DelLinOpButtonPushed(app,idx), true);
                
                % Create up button
                app.LinOpCell{idx}.butUp = uibutton(app.ForwardModel, 'push');
                app.LinOpCell{idx}.butUp.Text = '';
                app.LinOpCell{idx}.butUp.Icon = 'upArrow.png';
                app.LinOpCell{idx}.butUp.Position = [app.pos_left+310 pos_bott app.def_width*0.8 app.def_height];
                app.LinOpCell{idx}.butUp.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_UpLinOpButtonPushed(app,idx), true);
                
                % Create down button
                app.LinOpCell{idx}.butDown = uibutton(app.ForwardModel, 'push');
                app.LinOpCell{idx}.butDown.Text = '';
                app.LinOpCell{idx}.butDown.Icon = 'downArrow.png';
                app.LinOpCell{idx}.butDown.Position = [app.pos_left+285 pos_bott app.def_width*0.8 app.def_height];
                app.LinOpCell{idx}.butDown.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_DownLinOpButtonPushed(app,idx), true);
                
            end
            if nLinOps>0
                app.LinOpCell{1}.butUp.Enable = 'off';
                app.LinOpCell{nLinOps}.butDown.Enable = 'off';
            end
            
            % Update text for concatenation of linOps
            app.TextArea_ForwardModel.Value = {app.linOpText};
            
        end
        
        % -- Button pushed function: Button_PropButtonPushed
        function Button_PropButtonPushed(app,idx)
            
            % disable button
            app.LinOpCell{idx}.butProp.Enable='off';
            app.Button_AddLinOp.Enable='off';
            for ii=1:length(app.LinOpCell)
                app.LinOpCell{ii}.butDel.Enable='off';
                app.LinOpCell{ii}.butUp.Enable='off';
                app.LinOpCell{ii}.butDown.Enable='off';
            end
            
            % Create Figure
            hh=app.incr_height*(length(app.LinOpCell{idx}.parameters)+1);
            app.LinOpCell{idx}.SetPropGUI.fig = uifigure;
            app.LinOpCell{idx}.SetPropGUI.fig.Position  = app.GlobalBioImGUIUIFigure.Position  +  [app.GlobalBioImGUIUIFigure.Position(3) app.GlobalBioImGUIUIFigure.Position(4)-hh 0 -app.GlobalBioImGUIUIFigure.Position(4)+hh];
            app.LinOpCell{idx}.SetPropGUI.fig.Name  = 'Set Parameters';
            
            % Create Name
            app.LinOpCell{idx}.SetPropGUI.title = uilabel(app.LinOpCell{idx}.SetPropGUI.fig);
            app.LinOpCell{idx}.SetPropGUI.title.FontSize = 16;
            app.LinOpCell{idx}.SetPropGUI.title.FontWeight = 'bold';
            app.LinOpCell{idx}.SetPropGUI.title.Position = [19 hh-45 428 38];
            app.LinOpCell{idx}.SetPropGUI.title.Text = app.LinOpCell{idx}.label.Text;
            
            % Create Apply Changes button
            app.LinOpCell{idx}.SetPropGUI.butApplyChanges = uibutton(app.LinOpCell{idx}.SetPropGUI.fig, 'push');
            app.LinOpCell{idx}.SetPropGUI.butApplyChanges.Position = [350 hh-35 100 20];
            app.LinOpCell{idx}.SetPropGUI.butApplyChanges.Text = 'Apply Changes';
            app.LinOpCell{idx}.SetPropGUI.butApplyChanges.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_ApplyChangesLinOpPushed(app,idx), true);
            
            % Create See Doc  button
            app.LinOpCell{idx}.SetPropGUI.butSeeDoc = uibutton(app.LinOpCell{idx}.SetPropGUI.fig, 'push');
            app.LinOpCell{idx}.SetPropGUI.butSeeDoc.Position = [280 hh-35 50 20];
            app.LinOpCell{idx}.SetPropGUI.butSeeDoc.Text = 'Doc.';
            app.LinOpCell{idx}.SetPropGUI.butSeeDoc.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_SeeLinOpDocButtonPushed(app,idx), true);
            
            % Display parameters selection
            for ii=1:length(app.LinOpCell{idx}.parameters)
                pos_bott      = hh-(ii+0.5)*app.incr_height;
                % Info Button_
                app.LinOpCell{idx}.SetPropGUI.param{ii}.info = uibutton(app.LinOpCell{idx}.SetPropGUI.fig, 'push');
                app.LinOpCell{idx}.SetPropGUI.param{ii}.info.Icon = 'infoIcon.png';
                app.LinOpCell{idx}.SetPropGUI.param{ii}.info.Tooltip = {app.LinOpCell{idx}.parameters{ii}.info};
                app.LinOpCell{idx}.SetPropGUI.param{ii}.info.Position = [30 pos_bott 20 app.def_height];
                app.LinOpCell{idx}.SetPropGUI.param{ii}.info.Text = '';
                typ=app.LinOpCell{idx}.parameters{ii}.type;
                switch  typ
                    case 'file'
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.label = uilabel(app.LinOpCell{idx}.SetPropGUI.fig);
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.label.HorizontalAlignment = 'left';
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.label.Position = [60 pos_bott 80 app.def_height];
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.label.Text = [app.LinOpCell{idx}.parameters{ii}.name,' : '];
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.TextArea = uitextarea(app.LinOpCell{idx}.SetPropGUI.fig);
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.TextArea.Position = [150 pos_bott 220 app.def_height];
                        text=app.LinOpCell{idx}.parameters{ii}.val;
                        if isempty(text), text='Select a file (.png,.jpg,.tif,.mat)'; end
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.TextArea.Value=text;
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.butBrowse = uibutton(app.LinOpCell{idx}.SetPropGUI.fig, 'push');
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.butBrowse.Text = 'Browse';
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.butBrowse.Position = [380 pos_bott 60 app.def_height];
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.butBrowse.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_BrowseSetPropLinOpPushed(app,idx,ii), true);
                    case 'vecInt'
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.label = uilabel(app.LinOpCell{idx}.SetPropGUI.fig);
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.label.HorizontalAlignment = 'left';
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.label.Position = [60 pos_bott 80 app.def_height];
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.label.Text = [app.LinOpCell{idx}.parameters{ii}.name,' : '];
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.openBracket = uilabel(app.LinOpCell{idx}.SetPropGUI.fig);
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.openBracket.Position = [140 pos_bott-5 10 app.def_height*1.8];
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.openBracket.Text = '[';
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.openBracket.FontSize = 30;
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.closeBracket = uilabel(app.LinOpCell{idx}.SetPropGUI.fig);
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.closeBracket.Position = [232 pos_bott-5 10 app.def_height*1.8];
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.closeBracket.Text = ']';
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.closeBracket.FontSize = 30;
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.EditField = uieditfield(app.LinOpCell{idx}.SetPropGUI.fig,'text');
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.EditField.Position = [150 pos_bott 80 app.def_height];
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.EditField.Value=  app.LinOpCell{idx}.parameters{ii}.val;
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.EditField.ValueChangedFcn = createCallbackFcn(app, @(app,event) EditField_ValueSetPropLinOpChanged(app,idx,ii), true);
                end
                
            end
        end
        
        % -- Button pushed function: Button_SeeLinOpDocButtonPushed
        function Button_SeeLinOpDocButtonPushed(app, idx)
            web([app.doc_weblink 'linop.html#linop' lower(app.LinOpCell{idx}.name)], '-notoolbar');
        end
        
        % -- Button pushed function: Button_ApplyChangesLinOpPushed
        function Button_ApplyChangesLinOpPushed(app,idx)
            if (~isempty(app.logwd) && ishandle(app.logwd.fig)), close(app.logwd.fig); end
            err=0;msg={};
            for ii=1:length(app.LinOpCell{idx}.parameters)
                typ=app.LinOpCell{idx}.parameters{ii}.type;
                switch  typ
                    case 'vecInt'
                        tmp=arrayfun(@(x) [num2str(x),' '],str2num(app.LinOpCell{idx}.parameters{ii}.val),'UniformOutput',false);
                        tmp=horzcat(tmp{:}); tmp=tmp(1:end-1);
                        if ~isempty(app.LinOpCell{idx}.parameters{ii}.val) &&  (~strcmp(tmp,app.LinOpCell{idx}.parameters{ii}.val) || all(mod(str2num(app.LinOpCell{idx}.parameters{ii}.val),1)~=0))
                            err=1;
                            msg{length(msg)+1}=['Wrong entry for parameter ',app.LinOpCell{idx}.parameters{ii}.name];
                            msg{length(msg)+1}=' --> A sequence of integers separated by a space is required (e.g. 512 512)';
                            app.LinOpCell{idx}.SetPropGUI.param{ii}.label.FontColor='red';
                        end
                end
            end
            if err==0
                close(app.LinOpCell{idx}.SetPropGUI.fig);
                b=true;
                for ii=1:length(app.LinOpCell)
                    b = b & (isfield(app.LinOpCell{ii},'SetPropGUI') && ~ishandle(app.LinOpCell{ii}.SetPropGUI.fig));
                end
                if b
                    for ii=1:length(app.LinOpCell)
                        app.LinOpCell{ii}.butDel.Enable='on';
                        if ii~=1, app.LinOpCell{ii}.butUp.Enable='on';end
                        if ii~=length(app.LinOpCell), app.LinOpCell{ii}.butDown.Enable='on';end
                    end
                    app.Button_AddLinOp.Enable='on';
                end
                app.LinOpCell{idx}.butProp.Enable='on';
            else
                OpenLog(app,msg);
            end
        end
        
        % -- Value Changed function: EditField_ValueSetPropLinOpChanged
        function EditField_ValueSetPropLinOpChanged(app,idx,ii)
            app.LinOpCell{idx}.parameters{ii}.val=app.LinOpCell{idx}.SetPropGUI.param{ii}.EditField.Value;
        end
        
        % -- Button pushed function: Button_BrowseSetPropLinOpPushed
        function Button_BrowseSetPropLinOpPushed(app,idx,ii)
            [file,path]=uigetfile();
            if file~=0
                app.LinOpCell{idx}.SetPropGUI.param{ii}.TextArea.Value=[path,file];
                app.LinOpCell{idx}.parameters{ii}.val=[path,file];
            end
        end
        
        % -- Button pushed function: Button_DelLinOpButtonPushed
        function Button_DelLinOpButtonPushed(app,idx)
            
            % Delete previously displayed text in the hierarchy box
            if ~isempty(app.LinOpCell)
                deleteHierarchyLinOps(app);
            end
            
            % Add linOp name info
            tmp=app.LinOpCell;
            app.LinOpCell={};
            pos=1;
            for ii=1:length(tmp)
                if ii~=idx
                    app.LinOpCell{pos}=tmp{ii};
                    pos=pos+1;
                end
            end
            
            % Display updated text in the hierarchy box
            createHierarchyLinOps(app);
            
            % Enable addLinOp button if max number of linOps not reached
            if length(app.LinOpCell)<app.maxLinOp
                app.Button_AddLinOp.Enable = 'on';
            end
        end
        
        % -- Button pushed function: Button_UpLinOpButtonPushed
        function Button_UpLinOpButtonPushed(app,idx)
            
            % Delete previously displayed text in the hierarchy box
            if ~isempty(app.LinOpCell)
                deleteHierarchyLinOps(app);
            end
            
            % Reorganize the LinOpCell
            tmp=app.LinOpCell{idx-1};
            app.LinOpCell{idx-1}=app.LinOpCell{idx};
            app.LinOpCell{idx}=tmp;
            
            % Display updated text in the hierarchy box
            createHierarchyLinOps(app);
        end
        
        % -- Button pushed function: Button_DownLinOpButtonPushed
        function Button_DownLinOpButtonPushed(app,idx)
            
            % Delete previously displayed text in the hierarchy box
            if ~isempty(app.LinOpCell)
                deleteHierarchyLinOps(app);
            end
            
            % Reorganize the LinOpCell
            tmp=app.LinOpCell{idx+1};
            app.LinOpCell{idx+1}=app.LinOpCell{idx};
            app.LinOpCell{idx}=tmp;
            
            % Display updated text in the hierarchy box
            createHierarchyLinOps(app);
        end

    end
    
    % ======================================
    %           COST FUNCTION PANEL
    % ======================================
    methods (Access = private)
        % -- Initialize Cost Function Panel
        function InitializeCostFunctionPanel(app)
            
            % display both dropdown GUI lists
            app.DropDown_SelectDataFidelity.Items = app.dfListGUI;
            app.DropDown_Select_Regul.Items = app.regListGUI;
            
            % force the user to select a data-fidelity before a
            % regularization
            app.Button_Add_Regul.Enable                = 'off';
            app.Button_SeeDocRegul.Enable              = 'off';
            app.DropDown_Select_Regul.Enable           = 'off';
            app.AddPositivityConstraintCheckBox.Enable = 'off';
            
            % Set Tags
            app.Button_SeeDocDataFidelity.Tag = 'DF';
            app.Button_SeeDocRegul.Tag = 'Regul';
        end
              
        % -- Create data-fidelity term
        function createDataFidelity(app)
            
            % SHOULD BE A CALL TO A GENERIC FUNCTION (OR MORE)
            
            app.TextArea_CostFunction.Value = ['C(x) = ' app.DFstruct.notation '(x,y)'];
            
            % Position info
            pos_bott      = app.pos_bott_init+app.incr_height*0.85;
            
            % Add Regul name info (for the selection box below)
            app.DFstruct.label          = uilabel(app.CostFunction);
            app.DFstruct.label.Text     = [app.DFstruct.name, ' data-fidelity'];
            app.DFstruct.label.Position = [app.pos_left+40 pos_bott app.def_width*8 app.def_height];
            
            % Create delete button (for the selection box below)
            app.DFstruct.butDel                 = uibutton(app.CostFunction, 'push');
            app.DFstruct.butDel.Text            = '';
            app.DFstruct.butDel.Icon            = 'trashIcon.png';
            app.DFstruct.butDel.Position        = [app.pos_left+340 pos_bott app.def_width*0.8 app.def_height];
            app.DFstruct.butDel.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_DelDFButtonPushed(app), true);
            
            % Create parameters button
            app.DFstruct.butProp                 = uibutton(app.CostFunction, 'push');
            app.DFstruct.butProp.Text            = 'Set Parameters';
            app.DFstruct.butProp.FontWeight      = 'bold';
            app.DFstruct.butProp.Position        = [app.pos_left+230 pos_bott app.def_width*3.2 app.def_height];
            app.DFstruct.butProp.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_PropDFButtonPushed(app), true);
            
            % Create label of drop-down component (for the selection box below)
            app.DFstruct.ddlabel             = uilabel(app.CostFunction);
            app.DFstruct.ddlabel.Position    = [app.pos_left pos_bott app.def_width*1.5 app.def_height];
            app.DFstruct.ddlabel.FontWeight  = 'bold';
            app.DFstruct.ddlabel.FontSize    = 16;
            app.DFstruct.ddlabel.Text        = app.DFstruct.notation;
            
        end
        
        % -- Button pushed function: Button_DelDFButtonPushed
        function Button_DelDFButtonPushed(app)
            % SHOULD BE A CALL TO A GENERIC FUNCTION
        end
        
        % -- Button pushed function: Button_PropDFButtonPushed
        function Button_PropDFButtonPushed(app)
            
            % SHOULD BE A CALL TO A GENERIC FUNCTION
            
            % disable button
            app.DFstruct.butProp.Enable='off';
            app.Button_Add_DataFidelity.Enable='off';
            app.DFstruct.butDel.Enable='off';
            app.DFstruct.butUp.Enable='off';
            app.DFstruct.butDown.Enable='off';
            
            % Create Figure
            hh=app.incr_height*(length(app.DFstruct.parameters)+1);
            app.DFstruct.SetPropGUI.fig = uifigure;
            app.DFstruct.SetPropGUI.fig.Position  = app.GlobalBioImGUIUIFigure.Position  +  [app.GlobalBioImGUIUIFigure.Position(3) app.GlobalBioImGUIUIFigure.Position(4)-hh 0 -app.GlobalBioImGUIUIFigure.Position(4)+hh];
            app.DFstruct.SetPropGUI.fig.Name  = 'Set Parameters';
            
            % Create Name
            app.DFstruct.SetPropGUI.title = uilabel(app.DFstruct.SetPropGUI.fig);
            app.DFstruct.SetPropGUI.title.FontSize = 16;
            app.DFstruct.SetPropGUI.title.FontWeight = 'bold';
            app.DFstruct.SetPropGUI.title.Position = [19 hh-45 428 38];
            app.DFstruct.SetPropGUI.title.Text = app.DFstruct.label.Text;
            
            % Create Apply Changes button
            app.DFstruct.SetPropGUI.butApplyChanges = uibutton(app.DFstruct.SetPropGUI.fig, 'push');
            app.DFstruct.SetPropGUI.butApplyChanges.Position = [350 hh-35 100 20];
            app.DFstruct.SetPropGUI.butApplyChanges.Text = 'Apply Changes';
            app.DFstruct.SetPropGUI.butApplyChanges.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_ApplyChangesDFPushed(app), true);
            
            % Create See Doc  button
            app.DFstruct.SetPropGUI.butSeeDoc = uibutton(app.DFstruct.SetPropGUI.fig, 'push');
            app.DFstruct.SetPropGUI.butSeeDoc.Position = [280 hh-35 50 20];
            app.DFstruct.SetPropGUI.butSeeDoc.Text = 'Doc.';
            app.DFstruct.SetPropGUI.butSeeDoc.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_SeeDFDocButtonPushed(app), true);
            
            % Display parameters selection
            for ii=1:length(app.DFstruct.parameters)
                pos_bott      = hh-(ii+0.5)*app.incr_height;
                % Info Button_
                app.DFstruct.SetPropGUI.param{ii}.info = uibutton(app.DFstruct.SetPropGUI.fig, 'push');
                app.DFstruct.SetPropGUI.param{ii}.info.Icon = 'infoIcon.png';
                app.DFstruct.SetPropGUI.param{ii}.info.Tooltip = {app.DFstruct.parameters{ii}.info};
                app.DFstruct.SetPropGUI.param{ii}.info.Position = [30 pos_bott 20 app.def_height];
                app.DFstruct.SetPropGUI.param{ii}.info.Text = '';
                typ=app.DFstruct.parameters{ii}.type;
                switch  typ
                    case 'file'
                        app.DFstruct.SetPropGUI.param{ii}.label = uilabel(app.DFstruct.SetPropGUI.fig);
                        app.DFstruct.SetPropGUI.param{ii}.label.HorizontalAlignment = 'left';
                        app.DFstruct.SetPropGUI.param{ii}.label.Position = [60 pos_bott 80 app.def_height];
                        app.DFstruct.SetPropGUI.param{ii}.label.Text = [app.DFstruct.parameters{ii}.name,' : '];
                        app.DFstruct.SetPropGUI.param{ii}.TextArea = uitextarea(app.DFstruct.SetPropGUI.fig);
                        app.DFstruct.SetPropGUI.param{ii}.TextArea.Position = [150 pos_bott 220 app.def_height];
                        text=app.DFstruct.parameters{ii}.val;
                        if isempty(text), text='Select a file (.png,.jpg,.tif,.mat)'; end
                        app.DFstruct.SetPropGUI.param{ii}.TextArea.Value=text;
                        app.DFstruct.SetPropGUI.param{ii}.butBrowse = uibutton(app.DFstruct.SetPropGUI.fig, 'push');
                        app.DFstruct.SetPropGUI.param{ii}.butBrowse.Text = 'Browse';
                        app.DFstruct.SetPropGUI.param{ii}.butBrowse.Position = [380 pos_bott 60 app.def_height];
                        app.DFstruct.SetPropGUI.param{ii}.butBrowse.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_BrowseSetPropDFPushed(app,ii), true);
                    case 'vecInt'
                        app.DFstruct.SetPropGUI.param{ii}.label = uilabel(app.DFstruct.SetPropGUI.fig);
                        app.DFstruct.SetPropGUI.param{ii}.label.HorizontalAlignment = 'left';
                        app.DFstruct.SetPropGUI.param{ii}.label.Position = [60 pos_bott 80 app.def_height];
                        app.DFstruct.SetPropGUI.param{ii}.label.Text = [app.DFstruct.parameters{ii}.name,' : '];
                        app.DFstruct.SetPropGUI.param{ii}.openBracket = uilabel(app.DFstruct.SetPropGUI.fig);
                        app.DFstruct.SetPropGUI.param{ii}.openBracket.Position = [140 pos_bott-5 10 app.def_height*1.8];
                        app.DFstruct.SetPropGUI.param{ii}.openBracket.Text = '[';
                        app.DFstruct.SetPropGUI.param{ii}.openBracket.FontSize = 30;
                        app.DFstruct.SetPropGUI.param{ii}.closeBracket = uilabel(app.DFstruct.SetPropGUI.fig);
                        app.DFstruct.SetPropGUI.param{ii}.closeBracket.Position = [232 pos_bott-5 10 app.def_height*1.8];
                        app.DFstruct.SetPropGUI.param{ii}.closeBracket.Text = ']';
                        app.DFstruct.SetPropGUI.param{ii}.closeBracket.FontSize = 30;
                        app.DFstruct.SetPropGUI.param{ii}.EditField = uieditfield(app.DFstruct.SetPropGUI.fig,'text');
                        app.DFstruct.SetPropGUI.param{ii}.EditField.Position = [150 pos_bott 80 app.def_height];
                        app.DFstruct.SetPropGUI.param{ii}.EditField.Value=  app.DFstruct.parameters{ii}.val;
                        app.DFstruct.SetPropGUI.param{ii}.EditField.ValueChangedFcn = createCallbackFcn(app, @(app,event) EditField_ValueSetPropDFChanged(app,ii), true);
                end
                
            end
        end
        
        % -- Button pushed function: Button_ApplyChangesDFPushed
        function Button_ApplyChangesDFPushed(app)
            % SHOULD BE A CALL TO A GENERIC FUNCTION
        end
        
        % -- Button pushed function: Button_SeeDFDocButtonPushed
        function Button_SeeDFDocButtonPushed(app)
            % SHOULD BE A CALL TO A GENERIC FUNCTION
        end
        
        % -- Button pushed function: Button_BrowseSetPropLinOpPushed
        function Button_BrowseSetPropDFPushed(app,index)
            % SHOULD BE A CALL TO A GENERIC FUNCTION
        end
        
        % -- Button pushed function: EditField_ValueSetPropDFChanged
        function EditField_ValueSetPropDFChanged(app,index)
            % SHOULD BE A CALL TO A GENERIC FUNCTION
        end
        
        % -- Delete hierarchy of regularizations
        function deleteHierarchyRegul(app)
            
            % Minimum text before adding reg terms
            app.costText = ['C(x) = ' app.DFstruct.notation '(x,y)'];
            
            % Loop over all the selected regularizations
            % Note: 1st entry of RegulCell is data-fidelity
            for idx=1:length(app.RegulCell)
                delete(app.RegulCell{idx}.label);
                delete(app.RegulCell{idx}.butDel);
                delete(app.RegulCell{idx}.ddlabel);
            end
        end
        
        % -- Create hierarchy of regularizations
        function createHierarchyRegul(app)
            
            for idx=1:length(app.RegulCell)
                
                % Position info
                pos_bott      = app.pos_bott_init-(idx+1)*app.incr_height;
                
                % Add Regul name info (for the selection box below)
                app.RegulCell{idx}.label = uilabel(app.CostFunction);
                app.RegulCell{idx}.label.Text = [app.RegulCell{idx}.name, ' regularization'];
                app.RegulCell{idx}.label.Position = [app.pos_left+40 pos_bott-20 app.def_width*8 app.def_height];
                
                % Create label of drop-down component (for the selection box below)
                app.RegulCell{idx}.ddlabel = uilabel(app.CostFunction);
                app.RegulCell{idx}.ddlabel.Position = [app.pos_left pos_bott-20 app.def_width*1.5 app.def_height];
                app.RegulCell{idx}.ddlabel.FontWeight = 'bold';
                app.RegulCell{idx}.ddlabel.FontSize = 16;
                app.RegulCell{idx}.ddlabel.Text = ['R' char(8320+idx) ' : '];
                
                % Create parameters button
                app.DFstruct.butProp                 = uibutton(app.CostFunction, 'push');
                app.DFstruct.butProp.Text            = 'Set Parameters';
                app.DFstruct.butProp.FontWeight      = 'bold';
                app.DFstruct.butProp.Position        = [app.pos_left+230 pos_bott-20 app.def_width*3.2 app.def_height];
                app.DFstruct.butProp.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_PropRegulButtonPushed(app,idx), true);
                
                % Create delete button (for the selection box below)
                app.RegulCell{idx}.butDel = uibutton(app.CostFunction, 'push');
                app.RegulCell{idx}.butDel.Text = '';
                app.RegulCell{idx}.butDel.Icon = 'trashIcon.png';
                app.RegulCell{idx}.butDel.Position = [app.pos_left+340 pos_bott-20 app.def_width*0.8 app.def_height];
                app.RegulCell{idx}.butDel.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_DelRegulButtonPushed(app,idx), true);
                
                % Update text for concatenation of regs (for the text box above)
                app.costText = [app.costText ' + ' char(955) char(8320+idx) 'R' char(8320+idx) '(x)'];
            end
            
            % Update text for concatenation of linOps (for the text box above)
            app.TextArea_CostFunction.Value = {app.costText};
            
        end
        
    end
    
    % ======================================
    %           OPTIMIZATION PANEL
    % ======================================
    methods (Access = private)


    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % GENERAL ---------------
            warning off
            
            % MAIN PANEL --------------
            ListOfPipelines = GetListOfAvailablePipelines(app);
            if isempty(ListOfPipelines)
                ListOfPipelines = {'Select a pipeline','New','Browse'};
            else
                ListOfPipelines =  ['Select a pipeline','New','Browse',{ListOfPipelines{:}}];
            end
            app.SelectapipelineDropDown.Items = ListOfPipelines;
            
            % FORWARD MODEL PANEL ------------------
            ListOfLinOpsStr = GetListOfAvailableLinOps(app);
            app.DropDown_SelectLinOp.Items = ListOfLinOpsStr;
            app.Button_SeeDocLinOp.Tag = 'LinOp'; % See Doc button Tag
            
            % COST FUNCTION PANEL ------------------
            InitializeCostFunctionPanel(app);
            
        end

        % Button pushed function: Button_AddLinOp
        function ButtonPushed_AddLinOp(app, event)
            
            % Delete previously displayed text in the hierarchy box
            if ~isempty(app.LinOpCell)
                deleteHierarchyLinOps(app);
            end
            
            % Add linOp name info
            idxLinOp=length(app.LinOpCell)+1;
            app.LinOpCell{idxLinOp}.name=app.DropDown_SelectLinOp.Value;
            
            % Get parameters from comments
            text=splitlines(fileread(['LinOp',app.LinOpCell{idxLinOp}.name,'.m']));
            splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUInotation'))},'-','split'); % Get notation
            app.LinOpCell{idxLinOp}.notation=splitxt{2};
            splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUIcall'))},'-','split'); % Get call line
            app.LinOpCell{idxLinOp}.call=splitxt{2};
            ll=text(~cellfun(@isempty, regexp(text, 'GUIparam'))); % Get parameters
            for ii=1:length(ll)
                splitxt=regexp(ll{ii},'-','split');
                app.LinOpCell{idxLinOp}.parameters{ii}.name=splitxt{2};
                app.LinOpCell{idxLinOp}.parameters{ii}.type=splitxt{3};
                if strcmp('[]',splitxt{4}), text=''; else, text=splitxt{4}; end
                app.LinOpCell{idxLinOp}.parameters{ii}.val=text;
                app.LinOpCell{idxLinOp}.parameters{ii}.info=splitxt{5};
            end
            
            % Display updated text in the hierarchy box
            createHierarchyLinOps(app);
            
            % Disable addLinOp button if max number of linOps reached
            if length(app.LinOpCell)>=app.maxLinOp
                app.Button_AddLinOp.Enable = 'off';
            end
            
        end

        % Button pushed function: Button_SeeDocDataFidelity, 
        % Button_SeeDocLinOp, Button_SeeDocRegul
        function ButtonPushed_SeeDoc(app, event)
            tab=event.Source.Tag;
            switch tab
                case 'LinOp'
                    ll = ['linop.html#linop' lower(app.DropDown_SelectLinOp.Value)];
                case 'DF'
                    idx = strcmp(app.DropDown_SelectDataFidelity.Items,app.DropDown_SelectDataFidelity.Value);
                    ll = ['cost.html#cost' lower(app.dfListCode{idx})];
                case 'Regul'
                    % SEE WHAT TO DO
            end
            web([app.doc_weblink ll], '-notoolbar');
        end

        % Button pushed function: Button_Add_DataFidelity
        function ButtonPushed_AddDataFidelity(app, event)
            
            
            % Add code name of data-fidelity term
            for i=1:length(app.dfListGUI)
                if strcmp(app.DropDown_SelectDataFidelity.Value, app.dfListGUI{i})
                    app.DFstruct.name = app.dfListCode{i};
                end
            end
            
            % Get parameters from comments
            text=splitlines(fileread(['Cost',app.DFstruct.name,'.m']));
            splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUInotation'))},'-','split'); % Get notation
            app.DFstruct.notation=splitxt{2};
            splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUIcall'))},'-','split'); % Get call line
            app.DFstruct.call=splitxt{2};
            ll=text(~cellfun(@isempty, regexp(text, 'GUIparam'))); % Get parameters
            for ii=1:length(ll)
                splitxt=regexp(ll{ii},'-','split');
                app.DFstruct.parameters{ii}.name=splitxt{2};
                app.DFstruct.parameters{ii}.type=splitxt{3};
                if strcmp('[]',splitxt{4}), text=''; else, text=splitxt{4}; end
                app.DFstruct.parameters{ii}.val=text;
                app.DFstruct.parameters{ii}.info=splitxt{5};
            end
            
            % Display text in the hierarchy box
            createDataFidelity(app);
            
            % Then allow user to select a regularization term
            app.Button_Add_Regul.Enable                = 'on';
            app.Button_SeeDocRegul.Enable             = 'on';
            app.DropDown_Select_Regul.Enable           = 'on';
            app.AddPositivityConstraintCheckBox.Enable = 'on';
            
            % Forbid the user to select an additional DF term
            app.Button_Add_DataFidelity.Enable         = 'off';
            app.DropDown_SelectDataFidelity.Enable    = 'off';
            app.Button_SeeDocDataFidelity.Enable      = 'off';
            
        end

        % Button pushed function: Button_Add_Regul
        function ButtonPushed_AddRegul(app, event)
            % Delete previously displayed text in the hierarchy box
            if ~isempty(app.RegulCell)
                deleteHierarchyRegul(app);
            end
            
            % Add linOp name info
            app.RegulCell{length(app.RegulCell)+1}.name=app.DropDown_Select_Regul.Value;
            
            % Display updated text in the hierarchy box
            createHierarchyRegul(app);
            
            % Disable addLinOp button if max number of linOps reached
            if length(app.RegulCell)>=app.maxRegul
                app.Button_Add_Regul.Enable = 'off';
            end
            
        end

        % Button pushed function: Next
        function ButtonPushed_CheckFwdModel(app, event)
            % Number of LinOps
            nLinOps = length(app.LinOpCell);
            
            % Loop over LinOps
            for idx=1:nLinOps
                % LinOp Notation in the script
                Op=['H',num2str(idx)];
                
                % Loop Over parameters of the LinOp
                nlinesParams=length(app.script.params);
                for ii=1:length(app.LinOpCell{idx}.parameters)
                    
                    % Update parameters section
                    if isempty(app.LinOpCell{idx}.parameters{ii}.val)
                        app.script.params{nlinesParams+ii}=[Op,'_',app.LinOpCell{idx}.parameters{ii}.name,' = []'];
                    else
                        typ = app.LinOpCell{idx}.parameters{ii}.type;
                        switch  typ
                            case 'vecInt'
                                app.script.params{nlinesParams+ii}=[Op,'_',app.LinOpCell{idx}.parameters{ii}.name,' = [',app.LinOpCell{idx}.parameters{ii}.val,'];'];
                            case 'file'
                                ext = regexp(app.LinOpCell{idx}.parameters{ii}.val,'\.','split');
                                ext=ext{end};
                                switch ext
                                    case 'tif'
                                    case {'png','jpg'}
                                        app.script.params{nlinesParams+ii}=[Op,app.LinOpCell{idx}.parameters{ii}.name,' = double(imread(',app.LinOpCell{idx}.parameters{ii}.val,'));'];
                                    case 'mat'
                                    otherwise
                                        OpenLog(app,[app.LinOpCell{idx}.parameters{ii}.name,' of ',app.LinOpCell{idx}.name,' : Unknown file extension .',ext,' (Accepted formats: .tif, .png, .jpg, .mat)']);
                                end
                        end
                    end
                    
                    % Update Fwd Model section
                    
                end
            end
            %str=sprintf('%s\n',app.script.params{:})
        end

        % Button pushed function: SelectButton
        function ButtonPushed_SelectPipeline(app, event)
            pp=app.SelectapipelineDropDown.Value;
            deleteHierarchyLinOps(app);
            %% TO EXTEND FOR COST AND OPTI
            % delete...
            enable=true;
            switch pp
                case 'New'
                    app.PipelineEditField.Editable = 'on';
                    app.PipelineEditField.Value = 'MyPipeline';
                    app.pipeline.name=app.PipelineEditField.Value;
                    app.DescriptionTextArea.Value = 'Describe your pipeline';
                    app.pipeline.dscpt = app.DescriptionTextArea.Value;
                    %app.CostCell={};
                    app.LinOpCell={};
                case 'Select a pipeline'
                    app.PipelineEditField.Editable = 'off';
                    app.PipelineEditField.Value = 'Select a pipeline';
                    app.DescriptionTextArea.Value = '';
                    app.DescriptionTextAreaLabel.Enable = 'off';
                    app.DescriptionTextArea.Enable = 'off';
                    app.DescriptionTextArea.Editable = 'off';
                    app.SaveButton_2.Enable = 'off';
                    %app.CostCell={};
                    app.LinOpCell={};
                    enable=false;
                otherwise
                    cont=true;
                    if strcmp(pp,'Browse')
                        [file,path]=uigetfile();
                        if file~=0
                            Pipeline=load([path,file]);
                        else
                            cont=false;
                            enable=false;
                        end
                    else
                        Pipeline=load(['pipelines/',pp]);
                    end
                    if cont
                        app.PipelineEditField.Editable = 'off';
                        app.LinOpCell=Pipeline.LinOpCell;
                        %app.CostCell=Pipeline.CostCell;
                        app.pipeline=Pipeline.pipeline;
                        app.PipelineEditField.Value = app.pipeline.name;
                        app.DescriptionTextArea.Value = app.pipeline.dscpt;
                    end
            end
            createHierarchyLinOps(app);
            %% TO EXTEND FOR COST AND OPTI
            %createHierarchyRegul(app);
            %createDataFidelity(app);
            if enable
                app.DescriptionTextAreaLabel.Enable = 'on';
                app.DescriptionTextArea.Enable = 'on';
                app.DescriptionTextArea.Editable = 'on';
                app.SaveButton_3.Enable = 'on';
            end
        end

        % Button pushed function: SaveButton_3, SaveButton_5, 
        % SaveButton_6, SaveButton_7
        function ButtonPushed_Save(app, event)
            % Get the filename and the path to save the pipeline
            [file,path]=uiputfile('.mat','Save pipeline',[app.PipelineEditField.Value,'.mat']);
            % Save the properties LinOpCell, RegulCell, DFstruct, and pipeline
            if file~=0
                LinOpCell = app.LinOpCell;
                RegulCell = app.RegulCell;
                DFstruct = app.DFstruct;
                pipeline = app.pipeline;
                save([path,file],'LinOpCell','RegulCell','DFstruct','pipeline');
                clear LinOpCell RegulCell DFstruct pipeline
            end
        end

        % Value changed function: DescriptionTextArea
        function ValueChanged_DescriptionPipeline(app, event)
            app.pipeline.dscpt = app.DescriptionTextArea.Value;
        end

        % Value changed function: PipelineEditField
        function ValueChanged_NamePipeline(app, event)
            app.pipeline.name = app.PipelineEditField.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GlobalBioImGUIUIFigure = uifigure;
            app.GlobalBioImGUIUIFigure.Name = 'GlobalBioIm - GUI';
            app.TabGroup = uitabgroup(app.GlobalBioImGUIUIFigure);
            app.Main = uitab(app.TabGroup);
            app.Main.Title = 'Main';
            app.GlobalBioImLabel = uilabel(app.Main);
            app.GlobalBioImLabel.Text = 'GlobalBioIm';
            app.SaveButton_3 = uibutton(app.Main, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Save, true);
            app.SaveButton_3.Enable = 'off';
            app.SaveButton_3.Text = 'Save';
            app.SelectButton = uibutton(app.Main, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SelectPipeline, true);
            app.SelectButton.Text = 'Select';
            app.SelectapipelineDropDown = uidropdown(app.Main);
            app.PipelineEditFieldLabel = uilabel(app.Main);
            app.PipelineEditFieldLabel.Text = 'Pipeline';
            app.PipelineEditField = uieditfield(app.Main, 'text');
            app.PipelineEditField.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_NamePipeline, true);
            app.PipelineEditField.Editable = 'off';
            app.PipelineEditField.Value = 'Select a pipeline';
            app.DescriptionTextAreaLabel = uilabel(app.Main);
            app.DescriptionTextAreaLabel.Enable = 'off';
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.Main);
            app.DescriptionTextArea.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_DescriptionPipeline, true);
            app.DescriptionTextArea.Enable = 'off';
            app.CopyrightC2019TheGlobalBioImTeamLabel = uilabel(app.Main);
            app.CopyrightC2019TheGlobalBioImTeamLabel.Text = 'Copyright (C) 2019 - The GlobalBioIm Team';
            app.ForwardModel = uitab(app.TabGroup);
            app.ForwardModel.Title = 'Forward Model';
            app.TextArea_ForwardModel = uitextarea(app.ForwardModel);
            app.LabelTextArea = uilabel(app.ForwardModel);
            app.LabelTextArea.Text = 'Forward Model';
            app.Button_AddLinOp = uibutton(app.ForwardModel, 'push');
            app.Button_AddLinOp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_AddLinOp, true);
            app.Button_AddLinOp.Text = 'Select';
            app.Button_SeeDocLinOp = uibutton(app.ForwardModel, 'push');
            app.Button_SeeDocLinOp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SeeDoc, true);
            app.Button_SeeDocLinOp.Text = 'Doc.';
            app.OperatorLabel = uilabel(app.ForwardModel);
            app.OperatorLabel.Text = 'Operator';
            app.DropDown_SelectLinOp = uidropdown(app.ForwardModel);
            app.DropDown_SelectLinOp.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDown_SelectLinOp.Value = 'Test';
            app.FMPanel = uipanel(app.ForwardModel);
            app.FMPanel.Title = 'Hierarchy of Selected Operators';
            app.Button_3 = uibutton(app.ForwardModel, 'push');
            app.Button_3.Icon = 'infoIcon.png';
            app.Button_3.Text = '';
            app.Next = uibutton(app.ForwardModel, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_CheckFwdModel, true);
            app.Next.Text = 'Check Your Forward Model (Recommended)';
            app.SaveButton_6 = uibutton(app.ForwardModel, 'push');
            app.SaveButton_6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Save, true);
            app.SaveButton_6.Text = 'Save';
            app.CostFunction = uitab(app.TabGroup);
            app.CostFunction.Title = 'Cost Function';
            app.TextArea_CostFunction = uitextarea(app.CostFunction);
            app.CostFunctionLabel = uilabel(app.CostFunction);
            app.CostFunctionLabel.Text = 'Cost Function';
            app.Button_Add_DataFidelity = uibutton(app.CostFunction, 'push');
            app.Button_Add_DataFidelity.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_AddDataFidelity, true);
            app.Button_Add_DataFidelity.Text = 'Select';
            app.Button_SeeDocDataFidelity = uibutton(app.CostFunction, 'push');
            app.Button_SeeDocDataFidelity.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SeeDoc, true);
            app.Button_SeeDocDataFidelity.Text = 'Doc.';
            app.DatafidelityLabel = uilabel(app.CostFunction);
            app.DatafidelityLabel.Text = 'Data-fidelity';
            app.DropDown_SelectDataFidelity = uidropdown(app.CostFunction);
            app.DropDown_SelectDataFidelity.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDown_SelectDataFidelity.Value = 'Test';
            app.FMPanel_CostFunction = uipanel(app.CostFunction);
            app.FMPanel_CostFunction.Title = 'Selected Regularization Terms';
            app.Button_Add_Regul = uibutton(app.CostFunction, 'push');
            app.Button_Add_Regul.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_AddRegul, true);
            app.Button_Add_Regul.Text = 'Select';
            app.Button_SeeDocRegul = uibutton(app.CostFunction, 'push');
            app.Button_SeeDocRegul.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SeeDoc, true);
            app.Button_SeeDocRegul.Text = 'Doc.';
            app.RegularizerLabel = uilabel(app.CostFunction);
            app.RegularizerLabel.Text = 'Regularizer';
            app.DropDown_Select_Regul = uidropdown(app.CostFunction);
            app.DropDown_Select_Regul.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDown_Select_Regul.Value = 'Test';
            app.AddPositivityConstraintCheckBox = uicheckbox(app.CostFunction);
            app.AddPositivityConstraintCheckBox.Text = ' Add Positivity Constraint';
            app.FMPanel_CostFunction_2 = uipanel(app.CostFunction);
            app.FMPanel_CostFunction_2.Title = 'Selected Data-Fidelity Term';
            app.Button = uibutton(app.CostFunction, 'push');
            app.Button.Icon = 'infoIcon.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.CostFunction, 'push');
            app.Button_2.Icon = 'infoIcon.png';
            app.Button_2.Text = '';
            app.SaveButton_5 = uibutton(app.CostFunction, 'push');
            app.SaveButton_5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Save, true);
            app.SaveButton_5.Text = 'Save';
            app.Optimization = uitab(app.TabGroup);
            app.Optimization.Title = 'Optimization';
            app.FMPanel_CostFunction_3 = uipanel(app.Optimization);
            app.FMPanel_CostFunction_3.Title = 'Selected Algorithm';
            app.Button_Add_Regul_2 = uibutton(app.Optimization, 'push');
            app.Button_Add_Regul_2.Text = 'Select';
            app.Button_SeeDoc_Regul_2 = uibutton(app.Optimization, 'push');
            app.Button_SeeDoc_Regul_2.Text = 'Doc.';
            app.AlgorithmLabel = uilabel(app.Optimization);
            app.AlgorithmLabel.Text = 'Algorithm';
            app.DropDown_Select_Regul_2 = uidropdown(app.Optimization);
            app.DropDown_Select_Regul_2.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDown_Select_Regul_2.Value = 'Test';
            app.Button_4 = uibutton(app.Optimization, 'push');
            app.Button_4.Icon = 'infoIcon.png';
            app.Button_4.Text = '';
            app.RUNButton = uibutton(app.Optimization, 'push');
            app.RUNButton.Text = 'RUN';
            app.GenerateScriptButton = uibutton(app.Optimization, 'push');
            app.GenerateScriptButton.Text = 'Generate Script';
            app.RecapofAllSelectedParametersButton = uibutton(app.Optimization, 'push');
            app.RecapofAllSelectedParametersButton.Text = 'Recap of All Selected Parameters';
            app.DisplaythecostfunctionevolutionCheckBox = uicheckbox(app.Optimization);
            app.DisplaythecostfunctionevolutionCheckBox.Text = ' Display the cost-function evolution';
            app.FMPanel_CostFunction_4 = uipanel(app.Optimization);
            app.FMPanel_CostFunction_4.Title = 'Relevant References';
            app.SaveButton_7 = uibutton(app.Optimization, 'push');
            app.SaveButton_7.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Save, true);
            app.SaveButton_7.Text = 'Save';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_GlobalBioIm

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GlobalBioImGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GlobalBioImGUIUIFigure)
        end
    end
end